classdef LogLogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LogLogFittingUIFigure  matlab.ui.Figure
        AxesLimitsLabel        matlab.ui.control.Label
        UpperYEditField        matlab.ui.control.NumericEditField
        UpperYEditFieldLabel   matlab.ui.control.Label
        LowerYEditField        matlab.ui.control.NumericEditField
        LowerYEditFieldLabel   matlab.ui.control.Label
        FitLimitsLabel         matlab.ui.control.Label
        ExportButton           matlab.ui.control.Button
        InvertABSSwitch        matlab.ui.control.ToggleSwitch
        InvertABSLabel         matlab.ui.control.Label
        FitEqnTextArea         matlab.ui.control.TextArea
        FitEqnTextAreaLabel    matlab.ui.control.Label
        WavelengthText         matlab.ui.control.Label
        xLabel                 matlab.ui.control.Label
        MaxX                   matlab.ui.control.NumericEditField
        xLabel_2               matlab.ui.control.Label
        MinX                   matlab.ui.control.NumericEditField
        PrevButton             matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CallingApp % Store main app inside object CallingApp
        Index % Store Index from SpectraBuilderApp
        LinData % Store the LinData table passed to this app
        LogData % Store the LogData table passed to this app
        Time % Store the orginal X data
        LogTimeArray % Store the Log Array of the orginal X data
        InititalIndex % Specifiy the index of when the app started to return to
        plot_wavelengths % Store the names of the headers
        IndexSize % Store the size of KinInd
        LogLogABS % Store the uniform log Ydata
        LogLogTime % Store the uniform log Xdata
        xPoints % Store the x-data points of ROI object
        X1 % Store the 1st ROI line
        X2 % Store the 2nd ROI line
        Shade % Store shade array
        ShadeArea % Store shade area struct
        FitResult % Store the fit struct
        xData % Store prepared Xdata
        yData % Store prepared Ydata
        yFitData % Store fitted Ydata
        XPointsROI % Store ROI points in cell array
        XMin % Store minimum of uniform Xdata
        XMax % Store maximum of uniform Xdata
        Curve % Store fit plot
        Data % Store scatter plot
        LogPlot % Store plot variable only when log data is loaded in
        tempY % Temp Y variable for invert
        tempX % Temp x variable for invert
        Start % Store if first time setup
        Text % Store alpha variable in text object (label)
        leftX % Store left area text object (label)
        rightX % Store right area text object (label)
    end

    methods (Access = private)
        function [fitresult, gof] = createFit(app)
            %% Fit: 'Log-Log Graph' w/ check for inverse fit data
            [app.xData, app.yData] = prepareCurveData(app.LogLogTime, app.LogLogABS);

            % Set up fittype and options.
            ft = fittype( 'poly1' );
            excludedPoints = (app.xData < app.xPoints(1,1)) | (app.xData > app.xPoints(1,2));
            opts = fitoptions( 'Method', 'LinearLeastSquares' );
            opts.Robust = 'Bisquare';
            opts.Exclude = excludedPoints;

            % Fit model to data and save to vars
            [fitresult, gof] = fit( app.xData, app.yData, ft, opts );
            app.yFitData = feval(fitresult, app.xData);
            app.FitResult = fitresult;

            % Turn fit eqn into string and populate textbox
            fchar = evalc('fitresult');
            fstring = convertCharsToStrings(fchar);
            app.FitEqnTextArea.Value = fstring;
        end

        function shader(app, ~, ~)
            % If called without callback execute default
            if nargin == 1
                app.Shade = [];
                app.xPoints = [];

                % Change shaded area accordingly
                % Store new Ylimits from button
                tempY1 = app.UIAxes.YLim(1);
                tempY2 = app.UIAxes.YLim(2);

                % Clear any existing plots
                cla(app.UIAxes);

                app.UIAxes.YLim(1) = tempY1;
                app.UIAxes.YLim(2) = tempY2;

                %%%%% Default shaded area drawn/data & model
                app.XMin = min(app.LogLogTime);
                app.XMax = max(app.LogLogTime);

                % Draw both shaded areas up until XLims
                hold(app.UIAxes, 'on');
                RGarea = app.ShadeArea;

                % Create new areas
                app.Shade{1} = area(app.UIAxes, [app.XMin RGarea(1)], [-10 -10]);
                app.Shade{2} = area(app.UIAxes, [RGarea(2) app.XMax], [-10 -10]);

                % Set color to be same
                app.Shade{1}.FaceColor = [0.75 0.75 0.75];
                app.Shade{2}.FaceColor = [0.75 0.75 0.75];

                % Set default axes boundaries
                app.UIAxes.XLim = [app.XMin app.XMax];
                hold(app.UIAxes, 'off');

                % Store Lower and Upper X coordinates
                app.xPoints(1,1) = RGarea(1);
                app.xPoints(1,2) = RGarea(2);

                % Set basevalue first time around
                app.Shade{1,1}.BaseValue = app.UIAxes.YLim(2);

                %%%%% End of shaded area creation

                createFit(app);
                plotModel(app);
                plotData(app);

                cfit = app.FitResult;
                coeffvals = coeffvalues(cfit);
                alpha = ['\alpha = ', num2str((-1)*coeffvals(1), '%.2f')];
                x = app.xPoints(1,2) - 0.72;

                hold(app.UIAxes, 'on' );
                app.Text = text(app.UIAxes, x, app.UIAxes.YLim(2)-1, alpha, 'FontWeight', 'bold');
                app.leftX = text(app.UIAxes, app.ShadeArea(1,1) - 0.1, app.UIAxes.YLim(1) + 2, sprintf('%.2f',app.ShadeArea(1,1)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"right");
                app.rightX = text(app.UIAxes, app.ShadeArea(1,2) + 0.1, app.UIAxes.YLim(1) + 2, sprintf('%.2f',app.ShadeArea(1,2)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"left");
                hold (app.UIAxes, 'off');

                % Cell array to keep track of every ROI point position
                app.XPointsROI = [];

                % Implement drawline for either side of the area
                if ~isnan(app.xPoints(1,1)) && ~isnan(app.xPoints(1,2))
                    x = app.UIAxes.XLim(1);
                    y = app.UIAxes.YLim(1)-1;
                    w = app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
                    h = (app.UIAxes.YLim(2) + 1)-(app.UIAxes.YLim(1)-1);

                    app.X1 = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) 1], "Deletable",false,"InteractionsAllowed","translate",'Color', [0 0 0],"DrawingArea", [x y w h]);
                    app.X2 = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,2) app.UIAxes.YLim(1)-1; app.xPoints(1,2) app.UIAxes.YLim(2) + 1], "Deletable",false,"InteractionsAllowed","translate",'Color', [0 0 0],"DrawingArea", [x y w h]);

                    % Add callbacks for when point moves
                    addlistener(app.X1, "MovingROI", @(src,event)shader(app,src,event));
                    addlistener(app.X2, "MovingROI", @(src,event)shader(app,src,event));

                    % Store lines in vector
                    app.XPointsROI{1,1} = app.X1;
                    app.XPointsROI{1,2} = app.X2;
                end

                % To retrieve proper wavelengths/positions of observed data
                if app.CallingApp.TamOn == 0
                    app.WavelengthText.Text = "Displaying @" + app.plot_wavelengths(app.Index-1) + "nm";
                else
                    app.WavelengthText.Text = "Displaying " + app.CallingApp.positions(app.Index);
                end

            else
                % If ROI area is changed through manual mouse input
                % Replace the position of the shaded area to moved ROI pos
                app.ShadeArea=[app.XPointsROI{1,1}.Position(1),app.XPointsROI{1,2}.Position(1)];

                % Continue as normal
                updateLabels(app);
                default(app);
                createFit(app);
                updateModel(app);

                % Pause ever so quickly to update labels
                pause(0.00000000000001);

            end
        end

        function default(app)
            % Update both shaded areas up until XLims
            hold(app.UIAxes, 'on');

            RGarea = app.ShadeArea;
            AreaLeft = [app.XMin RGarea(1)];
            AreaRight = [RGarea(2) app.XMax];

            % Update new areas
            app.Shade{1}.XData = AreaLeft;
            app.Shade{2}.XData = AreaRight;

            hold(app.UIAxes, 'off');

            % Store Lower and Upper X coordinates
            app.xPoints(1,1) = app.Shade{1}.XData(2);
            app.xPoints(1,2) = app.Shade{2}.XData(1);
        end


        function plotModel(app)
            % Plot the model and its limits
            delete(app.Curve);

            hold(app.UIAxes, 'on' );
            app.Curve = line(app.UIAxes, app.xData, app.yFitData, 'Color', [1 0 0]);
            hold (app.UIAxes, 'off');

            app.MinX.Value = app.xPoints(1,1);
            app.MaxX.Value = app.xPoints(1,2);
        end

        function plotData(app)
            % Plot the data as scatter
            delete(app.Data);
            hold(app.UIAxes, 'on' );
            app.Data = scatter(app.UIAxes, app.LogLogTime, app.LogLogABS, 10, [0 0.4470 0.7410], 'filled');
            hold (app.UIAxes, 'off');
        end

        
        function updateLabels(app)
            % Change label values accordingly to the moved x pos
            app.leftX.Position = [app.ShadeArea(1,1) - 0.1, app.UIAxes.YLim(1) + 2];
            app.leftX.String = sprintf('%.2f',app.ShadeArea(1));
            app.rightX.Position = [app.ShadeArea(1,2) + 0.1, app.UIAxes.YLim(1) + 2];
            app.rightX.String = sprintf('%.2f',app.ShadeArea(2));

            % Gather updated data for updated annotation
            cfit = app.FitResult;
            coeffvals = coeffvalues(cfit);
            alpha = ['\alpha = ', num2str((-1)*coeffvals(1),'%.2f')];
            x = app.X2.Position(2,1)- 0.72;

            app.Text.Position = [x app.UIAxes.YLim(2)-1];
            app.Text.String = alpha;
        end
        
        function updateModel(app)
            app.Curve.XData = app.xData;
            app.Curve.YData = app.yFitData;

            app.MinX.Value = app.xPoints(1,1);
            app.MaxX.Value = app.xPoints(1,2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SolarSpecGUI)
            % Store main app object and other input arguments
            app.CallingApp = SolarSpecGUI;
            app.LinData = app.CallingApp.LinearData;
            app.LogData = app.CallingApp.LogarithmData;
            app.Time = app.CallingApp.time;
            app.LogTimeArray = app.CallingApp.LogTimeArr;
            app.InititalIndex = app.CallingApp.KinInd;
            app.Index = app.CallingApp.KinInd;
            app.plot_wavelengths = app.CallingApp.Plot_Wavelengths;
            app.ShadeArea = [];

            %Proper startup to keep app looking clean at first
            app.InvertABSSwitch.Value = 'Off';

            %Re-apply the app components/labels
            app.UIAxes.Visible = 1;
            app.FitEqnTextArea.Visible = 1;
            app.WavelengthText.Visible = 1;
            app.MinX.Visible = 1;
            app.MaxX.Visible = 1;
            app.FitEqnTextAreaLabel.Visible = 1;
            app.xLabel.Visible = 1;
            app.xLabel_2.Visible = 1;
            app.InvertABSSwitch.Visible = 1;
            app.InvertABSLabel.Visible = 1;
            app.Start = 1;

            %Index variables for which files the GUI is viewing
            if ~app.CallingApp.IsLogDataOnly
                app.IndexSize = width(app.LogData);
            else
                app.IndexSize = width(app.CallingApp.Table);
            end

            app.Index = app.Index + 1;
            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear loaded Data
                app.LogLogABS = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));

                % Log of the Lin-Spaced Linear Data. No longer used
                %LogLinABS = real(log10(app.LinData{:,KinInd}(app.Time>0)));
                %LogLinTime = real(log10(app.LinData{:,1}(app.Time>0)));
            else
                % Log of the Log-Spaced loaded Data
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.LogLogABS = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            end

            % Record a starting range for shaded area on startup
            if ~app.CallingApp.IsLogDataOnly
                default_shade = real(log10(app.LogData{:,2}(app.LogTimeArray>0)));
            else
                timeRG = table2array(app.CallingApp.Table(:,1));
                default_shade = real(log10(app.CallingApp.Table{:,2}(timeRG>0)));
            end

            % Use the starting range to update ShadedArea
            CenterTime = mean(default_shade);

            if CenterTime < 0
                app.ShadeArea = [CenterTime-1 CenterTime+1];
            else
                app.ShadeArea = [CenterTime+1 CenterTime-1];
            end

            shader(app);

%             % Add labels separately to view title, xlabel, and ylabel
%             app.UIAxes.Title.String = 'Log-Log Fitting';
%             app.UIAxes.YLabel.String = 'log(ABS)';
%             app.UIAxes.XLabel.String = 'log(Time)';

            % Update GUI elements to reflect limits
            app.LowerYEditField.Value = app.UIAxes.YLim(1);
            app.UpperYEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % Reset inverse switch and go to prev data index
            app.InvertABSSwitch.Value = 'Off';
            app.Index = app.Index - 1;
            if app.Index < 2
                app.Index = 2;
            end

            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear Data
                app.LogLogABS = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));
            else
                % Loaded LogData
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.LogLogABS = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            end

            shader(app);

%             % Add labels separately to view title, xlabel, and ylabel
%             app.UIAxes.Title.String = 'Log-Log Fitting';
%             app.UIAxes.YLabel.String = 'log(ABS)';
%             app.UIAxes.XLabel.String = 'log(Time)';
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Reset inverse switch and go to next data index
            app.InvertABSSwitch.Value = 'Off';
            app.Index = app.Index + 1;
            if app.Index > app.IndexSize
                app.Index = app.IndexSize;
            end

            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear Data
                app.LogLogABS = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));
            else
                % Loaded LogData
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.LogLogABS = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            end

            shader(app);

%             % Add labels separately to view title, xlabel, and ylabel
%             app.UIAxes.Title.String = 'Log-Log Fitting';
%             app.UIAxes.YLabel.String = 'log(ABS)';
%             app.UIAxes.XLabel.String = 'log(Time)';
        end

        % Value changed function: MinX
        function MinXValueChanged(app, event)
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue < app.XMin)
                latestvalue = app.XMin; 
                app.MinX.Value = latestvalue;
            elseif (latestvalue > app.X2.Position(1))
                uialert(app.LogLogFittingUIFigure,"Cannot input a limit past other ROI line","Input Error","Icon","Error");
                app.MinX.Value = event.PreviousValue;
                return
            end

            % Code to snap to nearest data point
            app.xPoints(1,1) = latestvalue;

            [~, line_index] = min(abs(app.LogLogTime - latestvalue));
            closest = app.LogLogTime(line_index);

            app.X1.Position = [closest app.UIAxes.YLim(1)*0.99; closest app.UIAxes.YLim(2)*1.01];
%                 app.X1.Label = sprintf('%.6f',closest);

            app.ShadeArea = [closest app.xPoints(1,2)];

            updateLabels(app);
            default(app);
            createFit(app);
            updateModel(app);

        end

        % Value changed function: MaxX
        function MaxXValueChanged(app, event)
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue > app.XMax)
                latestvalue = app.XMax;
                app.XMax = latestvalue;
            elseif (latestvalue < app.X1.Position(1))
                uialert(app.LogLogFittingUIFigure,"Cannot input a limit past other ROI line","Input Error","Icon","Error");
                app.MaxX.Value = event.PreviousValue;
                return
            end

            % Code to snap to nearest data point
            app.xPoints(1,2) = latestvalue;

            [~, line_index] = min(abs(app.LogLogTime - latestvalue));
            closest = app.LogLogTime(line_index);

            app.X2.Position = [closest app.UIAxes.YLim(1)*0.99; closest app.UIAxes.YLim(2)*1.01];
%                 app.X2.Label = sprintf('%.6f',closest);

            app.ShadeArea = [app.xPoints(1,1) closest];
            
            updateLabels(app);
            default(app);
            createFit(app);
            updateModel(app);
        end

        % Value changed function: InvertABSSwitch
        function InvertABSSwitchValueChanged(app, event)
            % Check the event value and proceed accordingly if inverted
            value = convertCharsToStrings(event.Value);

            if ~app.CallingApp.IsLogDataOnly
                if (value == 'On') %#ok<BDSCA>
                    % Copy temp variables for when you switch back
                    app.tempY = app.LogLogABS;
                    app.tempX = app.LogLogTime;

                    % Turn on and change data to inverted
                    inverseLog = (app.LogData{:,app.Index}).*(-1);
                    app.LogLogABS = real(log10(inverseLog(app.LogTimeArray<0)));
                    app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray<0)));

                    createFit(app);
                    updateModel(app);
                    plotData(app);
                    app.UIAxes.YLabel.String = 'log(-ABS)';
                else
                    % Turn back off and return to normal data
                    app.LogLogABS = app.tempY;
                    app.LogLogTime = app.tempX;

                    createFit(app);
                    updateModel(app);
                    plotData(app);
                    app.UIAxes.YLabel.String = 'log(ABS)';
                end
            else
                timeRG = table2array(app.CallingApp.Table(:,1));
                if (value == 'On') %#ok<BDSCA>
                    % Copy temp variables for when you switch back
                    app.tempY = app.LogLogABS;
                    app.tempX = app.LogLogTime;

                    % Turn on and change loaded log data to inverted
                    inverseLog = (app.CallingApp.Table{:,app.Index}).*(-1);
                    app.LogLogABS = real(log10(inverseLog(timeRG<0)));
                    app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG<0)));

                    createFit(app);
                    updateModel(app);
                    plotData(app);
                    app.UIAxes.YLabel.String = 'log(-ABS)';
                else
                    % Turn back off and return to normal loaded log data
                    app.LogLogABS = app.tempY;
                    app.LogLogTime = app.tempX;

                    createFit(app);
                    updateModel(app);
                    plotData(app);
                    app.UIAxes.YLabel.String = 'log(ABS)';
                end
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Store necessary variables for export
            time = app.LogLogTime;
            abs = app.LogLogABS;
            yfit = app.yFitData;
            fitwindow = [app.xPoints(1,1); app.xPoints(1,2)];
            fiteqn = [app.FitResult.p1; app.FitResult.p2];

            % Create blank array with titles
            p = {'LogTime', 'LogABS', 'yfitData', 'fitWindow', 'fitEquation'};
            A = nan(size(time,1),5);
            A(1:length(time),1) = time;
            A(1:length(abs),2) = abs;
            A(1:length(yfit),3) = yfit;
            A(1:length(fitwindow),4) = fitwindow;
            A(1:length(fiteqn),5) = fiteqn;

            % Convert 5 column vectors into table with appropriate names
            T = array2table(A);
            T.Properties.VariableNames(1:5) = p;

            % Add wavelength into title of filename
            f = sprintf('TASLogData%s.csv',app.WavelengthText.Text);
            place = fullfile(app.CallingApp.Path,f);
            writetable(T,place);
        end

        % Close request function: LogLogFittingUIFigure
        function LogLogFittingUIFigureCloseRequest(app, event)
            % Reset index to same value as when you opened from SpecBuilder
            app.CallingApp.KinInd = app.InititalIndex;

            % Re-enable the figure button and then delete the app
            app.CallingApp.LogLogFigureButton.Enable = 'on';
            delete(app)
        end

        % Value changed function: LowerYEditField
        function LowerYEditFieldValueChanged(app, event)
            value = app.LowerYEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.YLim(1) = value;
            end
        end

        % Value changed function: UpperYEditField
        function UpperYEditFieldValueChanged(app, event)
            value = app.UpperYEditField.Value;
            if isempty(value)
                return;
            elseif value > 0
                app.UIAxes.YLim(2) = 0;
            else
                app.UIAxes.YLim(2) = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LogLogFittingUIFigure = uifigure('Visible', 'off');
            app.LogLogFittingUIFigure.Name = 'Log-Log Fitting';
            app.LogLogFittingUIFigure.Icon = 'logo.png';
            app.LogLogFittingUIFigure.CloseRequestFcn = createCallbackFcn(app, @LogLogFittingUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.LogLogFittingUIFigure);
            title(app.UIAxes, 'Log-Log Fitting')
            xlabel(app.UIAxes, 'log(Time)')
            ylabel(app.UIAxes, 'log(ABS)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [-10 0];
            app.NextButton = uibutton(app.LogLogFittingUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.LogLogFittingUIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.MinX = uieditfield(app.LogLogFittingUIFigure, 'numeric');
            app.MinX.ValueChangedFcn = createCallbackFcn(app, @MinXValueChanged, true);
            app.xLabel_2 = uilabel(app.LogLogFittingUIFigure);
            app.xLabel_2.Text = 'Lower X';
            app.MaxX = uieditfield(app.LogLogFittingUIFigure, 'numeric');
            app.MaxX.ValueChangedFcn = createCallbackFcn(app, @MaxXValueChanged, true);
            app.xLabel = uilabel(app.LogLogFittingUIFigure);
            app.xLabel.Text = 'Upper X';
            app.WavelengthText = uilabel(app.LogLogFittingUIFigure);
            app.WavelengthText.Text = '';
            app.FitEqnTextAreaLabel = uilabel(app.LogLogFittingUIFigure);
            app.FitEqnTextAreaLabel.Text = 'Fit Eq''n:';
            app.FitEqnTextArea = uitextarea(app.LogLogFittingUIFigure);
            app.InvertABSLabel = uilabel(app.LogLogFittingUIFigure);
            app.InvertABSLabel.Text = {'Invert '; 'ABS'};
            app.InvertABSSwitch = uiswitch(app.LogLogFittingUIFigure, 'toggle');
            app.InvertABSSwitch.ValueChangedFcn = createCallbackFcn(app, @InvertABSSwitchValueChanged, true);
            app.ExportButton = uibutton(app.LogLogFittingUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FitLimitsLabel = uilabel(app.LogLogFittingUIFigure);
            app.FitLimitsLabel.Text = 'Fit Limits';
            app.LowerYEditFieldLabel = uilabel(app.LogLogFittingUIFigure);
            app.LowerYEditFieldLabel.Text = 'Lower Y';
            app.LowerYEditField = uieditfield(app.LogLogFittingUIFigure, 'numeric');
            app.LowerYEditField.ValueChangedFcn = createCallbackFcn(app, @LowerYEditFieldValueChanged, true);
            app.UpperYEditFieldLabel = uilabel(app.LogLogFittingUIFigure);
            app.UpperYEditFieldLabel.Text = 'Upper Y';
            app.UpperYEditField = uieditfield(app.LogLogFittingUIFigure, 'numeric');
            app.UpperYEditField.ValueChangedFcn = createCallbackFcn(app, @UpperYEditFieldValueChanged, true);
            app.AxesLimitsLabel = uilabel(app.LogLogFittingUIFigure);
            app.AxesLimitsLabel.Text = 'Axes Limits';
            % Show the figure after all components are created
            app.LogLogFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogLogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LogLogFittingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LogLogFittingUIFigure)
        end
    end
end