classdef SolarSpecGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrabuilderUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RightPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ExportSpectraDataButton         matlab.ui.control.Button
        ExportLogDataCSVButton          matlab.ui.control.Button
        Channel                         matlab.ui.control.Label
        KineticLevelDisplay             matlab.ui.control.Label
        BackgroundLvlDisplay            matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        AddButton                       matlab.ui.control.Button
        FileMetadataLabel               matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        PlotDetailsLabel                matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        TimeFreqLabel                   matlab.ui.control.Label
        TimeFreqEditField               matlab.ui.control.NumericEditField
        DigitalFilteringCheckBox        matlab.ui.control.CheckBox
        KeepSpecPlotYaxisfixedCheckBox  matlab.ui.control.CheckBox
        GridLayout6                     matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        GridLayout5                     matlab.ui.container.GridLayout
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LinearYscaleButton              matlab.ui.control.RadioButton
        LogYScaleButton                 matlab.ui.control.RadioButton
        CenterButton                    matlab.ui.control.Button
        KeepKinPlotaxisfixedCheckBox    matlab.ui.control.CheckBox
        SpecPlot                        matlab.ui.control.UIAxes
        KinPlot                         matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        LoadCreateError                 matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        SaveVarstoWorkspaceButton       matlab.ui.control.Button
        ExportFiguresButton             matlab.ui.control.Button
        ExportedLabel                   matlab.ui.control.Label
        LogLogFigureButton              matlab.ui.control.Button
        LoadLogData                     matlab.ui.control.Button
        GridLayout9                     matlab.ui.container.GridLayout
        LogDataButton                   matlab.ui.control.StateButton
        LinearDataButton                matlab.ui.control.StateButton
        LoadTDMSDataButton              matlab.ui.control.Button
    end


    properties (Access = private)
        LogApp % Store the second application in this object
        waves % Store list of wavelengths from header names
        BackgroundLevel % Store BackgroundLvl table
        LinDataSort % Store the sorted LinData table
        LinDataSortUnfilt
        LogDataSort % Store the sorted LogData table
        LogDataSortUnfilt
        dataRG % Store the DataRG table
        timeRG % Store the TimeRG table
        KinData % Store the loaded log data for only once
        changeAxes % Store boolean changeAxes if the axes has to change
        FixedYRange % Store the range of Y to keep axes fixed
        SpecYRange % Store the range of Y of the spectra axis
        SpecDataPlot % Store the spec plot data of the specified index
        SpecDataPlotUnfilt
        DataPlot % Hold the kin plot object
        ShadeArea % Array to store area limits
        Traces % Store the number of traces
        XPointsROI % Store ROI objects in cell array
        specHandle % Handle to hold the spectra line objects
        rst % To determine whether or not to reload data folder
        TimeWidths % Store time width table
        TimeFields % Store time centres in table rather than UIObject
        KinRef % Global variable to hold the kinplot yline
        SpecRef % Global variable to hold the specplot yline

        TimeLinSpecArray % Property to hold sorted table2array time values (lin, 1st column)
        TimeLogKinArray % Property to hold sorted table2array time values (log, 1st column)
        SortedYLinArray % Property to hold sorted table2array lin y values for each set (2:end)
        SortedYLinArrayUnfilt
%         SortedYLogArray % Property to hold sorted table2array log y values for each set (2:end)
        KinDataArray % Property to hold table2array y values current kinetics data
        bkgLevel % Property to hold table2array background level

        maxWave % Property to hold highest wavelength in file set
        minMinSpecData % Property to hold smallest element in matrix
        maxMaxSpecData % Property to hold largest element in matrix

        RGcolor % Property to hold color array
    end

    properties (Access = public)
        Path % Store the path of the data folder
        TamOn % Store whether TAM data is being used or not
        positions % Store list of positions from header names

        % For the loadTDMS script
        Channels % Holds the chosen channel names of all .tdms files
        RedGreen % Holds boolean values to determine colour of Channels text

        % These properties are essentially the input arguments for LogLog app
        LinearData % Store the LinData table
        LinearUnfiltered % Store the LinData that is never filtered
        LogarithmData % Store the LogData table
        LogarithmUnfiltered % Store the LogData table that is never filtered
        time % Store the original X data
        LogTimeArr % Store the Log of the X data in an array
        KinInd % Store the index of which data file we are looking at
        Plot_Wavelengths % Sorted string2double wavelengths nums
        IsLogDataOnly % Store if LogData is loaded and not calculated
    end

    methods (Access = private)

        function clearProperties(app)
            % Set all properties back to default values
            app.TamOn = 0;
            app.waves = {};
            app.positions = {};
            app.BackgroundLevel = {};
            app.LinDataSort = {};
            app.LogDataSort = {};
            app.dataRG = {};
            app.timeRG = {};
            app.IsLogDataOnly = 0;
            app.changeAxes = 1;
            app.FixedYRange = [];
            app.SpecYRange = [];
            app.SpecDataPlot = [];
            app.SpecDataPlotUnfilt = [];
            delete(app.DataPlot);
            app.ShadeArea = [];
            app.Traces = [];
            delete(app.XPointsROI)
            app.LinearData = {};
            app.LogarithmData = {};
            app.time = [];
            app.LogTimeArr = [];
            app.KinInd = 1;
            app.Plot_Wavelengths = {};
            app.KinData = [];
            app.maxWave = [];
            app.minMinSpecData = [];
            app.maxMaxSpecData = [];

            app.Channels = strings(0);
            app.RedGreen = [];

            app.BackgroundLvlDisplay.Text = "Background level is _ V";
            app.KineticLevelDisplay.Text = "Displaying kinetic at _ nm";
            app.Channel.Text = "";

            app.TimeLinSpecArray = [];
            app.TimeLogKinArray = [];
            app.SortedYLinArray = [];
            app.SortedYLinArrayUnfilt = [];
            app.KinDataArray = [];

            app.LinearYscaleButton.Value = true;
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.KeepSpecPlotYaxisfixedCheckBox.Value = 0;
            app.TimeFields = [1E-6; 1E-5; 1E-4; 1E-3; 1E-2; 1E-1];
            app.TimeWidths = [0.1; 0.1; 0.1; 0.1; 0.1; 0.1];

            cla(app.KinPlot)
            cla(app.SpecPlot)

            app.DigitalFilteringCheckBox.Value = 0;
            app.TimeFreqEditField.Enable = 0;
            app.TimeFreqEditField.Editable = 0;

%             % Set labels on clear
%             app.KinPlot.Title.String = "Kinetics";
%             app.KinPlot.XLabel.String = "Time (s)";
%             app.KinPlot.YLabel.String = "ΔOD or %Abs";
%             app.SpecPlot.Title.String = "Spectra";
%             app.SpecPlot.XLabel.String = "Wavelength (nm)";
%             app.SpecPlot.YLabel.String = "ΔOD or %Abs";
        end

        function RGBuildSpecData(app, src, ~)
            % If called without ROI callback, execute default
            if nargin == 1
                % Set default relative width to 10%
                SpecWidth = 0.10;

                % Preallocate for faster speeds
                app.SpecDataPlot = [];
                app.SpecDataPlotUnfilt = [];
                r = size(app.LinDataSort(:,2:end),2);
                c = size(app.TimeFields,1);
                app.SpecDataPlot = nan(r,c);
                app.SpecDataPlotUnfilt = nan(r,c);

                if isempty(get(app.KinPlot, 'Children')) == 1
                    % Set color function with expected num of colors
                    app.RGcolor = turbo(size(app.TimeFields,1)+1);
                    app.RGcolor = flipud(app.RGcolor);

                    for Spec = 1:1:size(app.TimeFields)
                        CenterTime = app.TimeFields(Spec);
                        StartTime = CenterTime *(1 - SpecWidth/2);
                        EndTime = CenterTime * (1 + SpecWidth/2);

                        StartInd = find(StartTime > app.TimeLinSpecArray,1,'last') + 1;
                        EndInd = find(EndTime < app.TimeLinSpecArray,1,'first') - 1;

                        app.SpecDataPlot(:,Spec) = mean(app.SortedYLinArray(StartInd:EndInd,:),1);
                        app.SpecDataPlotUnfilt(:,Spec) = mean(app.SortedYLinArrayUnfilt(StartInd:EndInd,:),1);
                        app.ShadeArea(Spec,1:2) = [StartTime EndTime];
                        app.TimeWidths(Spec,1) = EndTime - StartTime;
                    end

                    % Set max and min elements since data has been set
                    app.minMinSpecData = min(min(app.SpecDataPlot));
                    app.maxMaxSpecData = max(max(app.SpecDataPlot));

                    % Start of plotting in axes KinPlot
                    grid(app.KinPlot, 'off')
                    updateData(app);
                    YCheck(app);
                    drawAreas(app);
                    plotKinData(app);

                else
                    Builder(app);
                    updateData(app);
                    plotKinData(app);
                    YCheck(app);

                end

                plotSpecData(app,app.RGcolor)
                RGPlot(app,0);
            else
                % Deal with callback; handle mouse input
                % Variables carrying all the new data
                ind = str2double(src.Tag);

                % Conditional variables for different listeners (reshape vs translate)
                newwidth = src.Position(3);
                oldwidth = app.TimeWidths(ind);
                currentCenter = app.TimeFields(ind);

                % RESHAPE ROI
                if ~(oldwidth == newwidth)

                    % Then we are manually reshaping left side so adjust the right
                    if ~(src.Position(1) == app.ShadeArea(ind,1))
                        app.TimeWidths(ind) = 2*(currentCenter - src.Position(1));
                        app.XPointsROI(ind,1).Position(3) = app.TimeWidths(ind);

                        % Then we are manually reshaping right side so do nothing
                    else
                        app.XPointsROI(ind,1).Position(3) = app.TimeWidths(ind);
                    end

                    % TRANSLATE ROI
                else
                    % We only know the x position of left most line.
                    % We need to use this knowldge to calculate new center but keeping same relative width

                    newCenter = src.Position(1)/(1 - (app.UITable.Data{ind,2}/200));
                    app.XPointsROI(ind,1).Position(3) = newCenter*(app.UITable.Data{ind,2}/100);
                    app.TimeWidths(ind) = app.XPointsROI(ind,1).Position(3);
                    app.TimeFields(ind) = newCenter;
                end
                Builder(app);
                RGPlot(app,ind);
                %
                %                 % Display new UITable values
                %                 data(:,1) = arrayfun(@(x) sprintf('%.2e\n',x),app.TimeFields,'un',0);
                %                 data(:,2) = num2cell(round((app.TimeWidths./app.TimeFields).*100));
                %
                %                 app.UITable.Data = data;
            end
        end

        function RGPlot(app,ind)
            % Keep X scale Logarithmic
            app.KinPlot.XScale = 'log';

            % Update new spectral data if there is any
            if ind >= 1
                updateSpecData(app,ind);
            end

            % Check if there are more than one tdms file opened. Change plot accordingly
            [m,~] = size(app.SpecDataPlot);
            if m > 1
                % Check if Y-axis is fixed
                if app.changeAxes
                    if app.TamOn == 0
                        app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                        app.SpecPlot.YLim = app.SpecYRange;

                    else
                        app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                    end
                else
                    if app.TamOn == 0
                        app.SpecPlot.YLim = app.SpecYRange;
                    end
                end
            else
                if app.changeAxes
                    if app.TamOn == 0
                        if app.Plot_Wavelengths(1) == app.maxWave
                            app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                            xLim = [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1];

                            app.SpecPlot.YLim = app.SpecYRange;
                            app.SpecPlot.XLim = xLim;

                        else
                            app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                            xLim = [app.Plot_Wavelengths(1) app.maxWave];

                            app.SpecPlot.YLim = app.SpecYRange;
                            app.SpecPlot.XLim = xLim;
                        end
                    else
                        app.SpecYRange = [app.minMinSpecData app.maxMaxSpecData];
                    end
                else
                    if app.TamOn == 0
                        if app.Plot_Wavelengths(1) == app.maxWave
                            xLim = [app.Plot_Wavelengths(1) app.maxWave];

                            app.SpecPlot.YLim = app.SpecYRange;
                            app.SpecPlot.XLim = xLim;
                        else
                            xLim = [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1];

                            app.SpecPlot.YLim = app.SpecYRange;
                            app.SpecPlot.XLim = xLim;
                        end
                    end
                end
            end

            % Display new UITable values
            data(:,1) = arrayfun(@(x) sprintf('%.2e\n',x),app.TimeFields,'un',0);
            data(:,2) = num2cell(round((app.TimeWidths./app.TimeFields).*100));

            app.UITable.Data = data;
        end

        function Builder(app,~)
            % Calculate new spec data of time slice
            for i = 1:1:size(app.TimeFields,1)
                CenterTime = app.TimeFields(i);

                StartTime = CenterTime * (1 - (app.TimeWidths(i)/CenterTime)/2);
                EndTime = CenterTime * (1 + (app.TimeWidths(i)/CenterTime)/2);

                StartInd = find(StartTime > app.TimeLinSpecArray,1,'last') + 1;

                EndInd = find(EndTime < app.TimeLinSpecArray,1,'first') - 1;

                if EndInd > StartInd
                    app.SpecDataPlot(:,i) = mean(app.SortedYLinArray(StartInd:EndInd,:),1);
                    app.SpecDataPlotUnfilt(:,i) = mean(app.SortedYLinArrayUnfilt(StartInd:EndInd,:),1);
                else
                    app.SpecDataPlot(:,i) = mean(app.SortedYLinArray(EndInd:StartInd,:),1); % Index EndInd exceeds bounds of 1562500
                    app.SpecDataPlotUnfilt(:,i) = mean(app.SortedYLinArrayUnfilt(EndInd:StartInd,:),1);
                end

                if nargin == 1
                    app.ShadeArea(i,1:2) = [StartTime EndTime];
                end
            end

            % Reset max and min elements since data has been reset
            app.minMinSpecData = min(min(app.SpecDataPlot));
            app.maxMaxSpecData = max(max(app.SpecDataPlot));
        end

        function updateData(app)
            % Time variables (x-component stay the same so don't update)
            % Pull most recent data and populate properties
            kindata = app.dataRG(:,2:end);
            KinDataPlot = kindata(:,app.KinInd);
            app.KinDataArray = table2array(KinDataPlot);

            % Display the proper wavelengths/position text depending on TAS or TAM data
            if app.TamOn == 0
                app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
                app.BackgroundLvlDisplay.Text = "Background level is " + app.bkgLevel(1,app.KinInd) + " V";
            else
                app.KineticLevelDisplay.Text = "Disp: " + app.positions(app.KinInd);
                app.BackgroundLvlDisplay.Text = "Background level was " + app.bkgLevel(1,app.KinInd) + " V";
            end

            % Display proper channel with colour
            app.Channel.Text = "Selected channel is " + app.Channels(app.KinInd,1);
            if app.RedGreen(app.KinInd,1) == 1
                app.Channel.FontColor = "g";
            else
                app.Channel.FontColor = "r";
            end
        end

        function plotKinData(app)
            % Plot reference Y = 0 line if Y scale is linear.
            if strcmp(app.KinPlot.YScale,'linear') == 1
                % Make sure to delete previous ylines since we don't
                % clear, then send yline to the bottom for easier access to ROIs
                if ishandle(app.KinRef)
                    delete(app.KinRef)
                end

                app.KinRef = yline(app.KinPlot,0);
%                 uistack(app.KinRef, "bottom");
            end

            % Plot the data on axes figure
            delete(app.DataPlot)
            hold(app.KinPlot, 'on');
            app.DataPlot = line(app.KinPlot, app.TimeLogKinArray, app.KinDataArray, "Color", 'b');
            hold(app.KinPlot, 'off');
        end

        function plotSpecData(app, RGcolor)
            delete(app.SpecRef);
            delete(app.specHandle);

            % Preallocate graphics objects
            app.specHandle = gobjects(size(app.SpecDataPlot,2),1);

            % Plot new respective wavelengths
            for i = 1:1:size(app.SpecDataPlot,2)
                app.specHandle(i,1) = line(app.SpecPlot, app.Plot_Wavelengths,app.SpecDataPlot(:,i), 'Color', RGcolor(i,:),'Marker','s');
                hold(app.SpecPlot, 'on');
            end
            app.SpecRef = yline(app.SpecPlot,0);
            hold(app.SpecPlot, 'off');
        end

        function drawAreas(app)
            % Delete any previous data in cell array and preallocate
            sz = size(app.TimeFields,1);

            % Check if in LogY Scale to change height to non negative
            if (app.ButtonGroup.SelectedObject == app.LogYScaleButton)
                height = (app.FixedYRange(2)-app.FixedYRange(1))*(.80);
            else
                height = (app.FixedYRange(2)-app.FixedYRange(1))*(1.50);
            end

            % Set starting x and width for drawing area of each ROI
            x = app.KinPlot.XLim(1);
            w = app.KinPlot.XLim(2) - app.KinPlot.XLim(1);

            % Preallocate graphics objects
            app.XPointsROI = gobjects(sz,1);

            for i = 1:1:sz
                % Create our editable areas (as rectangle objects)
                app.XPointsROI(i,1) = images.roi.Rectangle(app.KinPlot, "Position", [app.ShadeArea(i,1) app.FixedYRange(1)*(1.1) app.TimeWidths(i) height], 'Color', app.RGcolor(i,:), 'DrawingArea',[x app.FixedYRange(1)*(1.1) w height] ,'Tag', num2str(i), 'UserData', i, 'LineWidth', 1); % 'MarkerSize', 4);

                % Add callbacks for when point if moved
                addlistener(app.XPointsROI(i,1), "MovingROI", @(src,event)RGBuildSpecData(app,src,event));
            end

        end

        function updateAreas(app)
            % Only update new height parameters between data
            % Check if in LogY Scale to change height to non negative
            if (app.ButtonGroup.SelectedObject == app.LogYScaleButton)
                height = (app.FixedYRange(2)-app.FixedYRange(1))*(.80);
            else
                height = (app.FixedYRange(2)-app.FixedYRange(1))*(1.40);
            end

            for i = 1:1:size(app.TimeFields)
                app.XPointsROI(i,1).Position = [app.ShadeArea(i,1) app.FixedYRange(1)*(1.1) app.TimeWidths(i) height];
            end
        end

        function YCheck(app)
            % Check if Y-axis is to be kept constant
            if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                app.FixedYRange = [min(app.KinDataArray) max(app.KinDataArray)];
                
                app.KinPlot.XLim = [1E-7 max(app.TimeLogKinArray)];
                app.KinPlot.YLim = app.FixedYRange;
            end

            % Alter accordingly if in LogY scale
            if app.ButtonGroup.SelectedObject == app.LogYScaleButton
                app.FixedYRange(1) = app.KinPlot.YTick(1)*(0.10);
            end
        end

        function plotLogData(app)
            % Populate variables with loaded properties
            updateData(app);
            app.Traces = size(app.dataRG, 2) - 1;

            plotKinData(app);

            % Check if Y-axis is to be kept constant
            if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                axis(app.KinPlot,[1E-7 max(app.TimeLogKinArray) min(app.KinDataArray) max(app.KinDataArray)]);
            else
                axis(app.KinPlot,[1E-7 max(app.TimeLogKinArray) app.FixedYRange]);
            end

            % Keep X scale Logarithmic
            app.KinPlot.XScale = 'log';

            % Make sure to delete previous ylines since we don't clear
            if ishandle(app.KinRef)
                delete(app.KinRef);
            end

            % Check if lin or log scale
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.LogYScaleButton
                app.KinPlot.YScale = 'log';
            else
                app.KinPlot.YScale = 'linear';
                app.KinRef = yline(app.KinPlot, 0);
            end

            % Display proper wavelength on app
            if app.TamOn == 0
                app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
            else
                app.KineticLevelDisplay.Text = "Disp: " + app.positions(app.KinInd);
            end
        end

        function deleteRow(app, indices)
            % Find index of row
            ind = indices(1);

            % Clear variables connected to row
            app.UITable.Data(ind,:) = [];
            app.TimeFields(ind) = [];
            app.TimeWidths(ind) = [];
            app.ShadeArea(ind,:) = [];

            % Preallocate for faster speeds now that size has changed
            app.SpecDataPlot = [];
            r = size(app.LinDataSort(:,2:end),2);
            c = size(app.TimeFields, 2);
            app.SpecDataPlot = nan(r,c);

            % Build new spec data since row got deleted
            Builder(app);
            app.RGcolor = turbo(size(app.TimeFields,1)+1);
            app.RGcolor = flipud(app.RGcolor);

            % Only delete specified ROI and spectra line
            delete(app.XPointsROI(ind,1));
            app.XPointsROI(ind) = [];
            delete(app.specHandle(ind,1));
            app.specHandle(ind) = [];

            % Only reassign tags for efficiency instead of redrawing
            for i = 1:size(app.XPointsROI,1)
                app.XPointsROI(i,1).Tag = num2str(i);
                app.XPointsROI(i,1).Color = app.RGcolor(i,:);
                app.specHandle(i,1).Color = app.RGcolor(i,:);
            end

            % Make sure axes still fits the best and then draw new ROIs
            YCheck(app);
            RGPlot(app,0);
        end

        function addRow(app)
            % Determine end magnitude by splitting exponent of last element
            last = (app.UITable.Data(end, 1));
            spt = split (last,'e');
            num2 = spt(2);
            mag0 = str2double(num2);
            mag = mag0 + 1;
            mag = 10^mag;

            % Add new field values, then clear and update app
            ind = length(app.XPointsROI);
            app.TimeFields(end + 1) = 1.00*mag;
            app.TimeWidths(end + 1) = 0.1*app.TimeFields(end);

            % Preallocate for faster speeds now that size has changed
            app.SpecDataPlot = [];
            r = size(app.LinDataSort(:,2:end),2);
            c = size(app.TimeFields, 2);
            app.SpecDataPlot = nan(r,c);

            % Check if in LogY Scale to change height to non negative
            if (app.ButtonGroup.SelectedObject == app.LogYScaleButton)
                height = (app.FixedYRange(2)-app.FixedYRange(1))*(.80);
            else
                height = (app.FixedYRange(2)-app.FixedYRange(1))*(1.50);
            end

            % More efficient, it doesn't redraw everything, only deletes rectangles now
            Builder(app);

            % Update colour depending on size of table
            app.RGcolor = turbo(size(app.TimeFields,1) + 1);
            app.RGcolor = flipud(app.RGcolor);

            % Draw a new rectangle ROI
            app.XPointsROI(end + 1,1) = images.roi.Rectangle(app.KinPlot, "Position", [app.ShadeArea(end,1) app.FixedYRange(1)*(1.1) app.TimeWidths(end) height], 'DrawingArea',[app.KinPlot.XLim(1) app.FixedYRange(1)*1.1 app.KinPlot.XLim(2) - app.KinPlot.XLim(1) height] ,'Tag', num2str(ind + 1), 'UserData', ind + 1, 'LineWidth', 1); % 'MarkerSize', 4);

            % Assign new colour for each
            for i = 1:size(app.XPointsROI,1)
                app.XPointsROI(i,1).Color = app.RGcolor(i,:);
            end

            % Add callbacks for when ROI is moving for spectra and moved for norm
            addlistener(app.XPointsROI(ind+1,1), "MovingROI", @(src,event)RGBuildSpecData(app,src,event));
            YCheck(app);

            % Draw new spectra and update table
            plotSpecData(app,app.RGcolor);
            RGPlot(app,0);
        end

        function updateSpecData(app,ind)
            app.specHandle(ind,1).XData = app.Plot_Wavelengths;
            app.specHandle(ind,1).YData = app.SpecDataPlot(:,ind);
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Keep most buttons invisible for cleaner startup look
            app.GridLayout9.Visible = false;
            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;
            app.SaveVarstoWorkspaceButton.Visible = false;
            app.LogLogFigureButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.ResetButton.Visible = false;
            app.rst = 0;

            try
                % Check if MATLAB addons are installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox');
                matlab.addons.isAddonEnabled('Curve Fitting Toolbox');
            catch ME
                disp(ME);
                app.LoadCreateError.Text = "Please ensure the 'Image Processing Toolbox' and the 'Curve Fitting Toolbox' are installed to continue";
                return;
            end
        end

        % Button pushed function: LoadTDMSDataButton
        function loadDataButton(app, event)
            clearProperties(app);

            % Check if we start from startup fcn or reset fcn and set path accordingly
            if app.rst == 0
                app.Path = "";
                % Disable button until data is loaded
                app.LoadTDMSDataButton.Enable = 'off';
                try
                    app.LoadCreateError.Text = "Loading data...";
                    FolderPath = uigetdir;
                    FolderContent = dir(FolderPath); %#ok<NASGU>
                    uiwait(FolderPath);
                catch
                    app.LoadCreateError.Text = 'Invalid directory, try again';
                end
                app.LoadTDMSDataButton.Enable = 'on';
            else
                FolderPath = app.Path;
                FolderContent = dir(FolderPath); %#ok<NASGU>
            end

            % Load the appropriate script file to handle loaded TDMS data
            try
                SpectraBuilderloadTDMSfolder;
            catch ERROR
                switch ERROR.identifier
                    case 'MATLAB:UndefinedFunction'
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        disp(ERROR);
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Unknown error - view command line';
                        disp(ERROR);
                        return;
                end
            end

            % Separate property to distinguish proper path
            app.Path = FolderPath;

            % Set all default variables from the loadTDMSfolder to public and
            % private properties for both applications to call on
            app.LinearData = LinData;
            app.LogarithmData = LogData;
            app.TamOn = tamOn;
            app.time = Time;
            app.LogTimeArr = LogTimeArray;
            app.BackgroundLevel = BackgroundLvl;
            app.KinInd = 1;
            app.IsLogDataOnly=false;
            app.changeAxes = true;
            app.rst = 0;
            app.ShadeArea = [];

            % Reveal all the hidden objects
            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.SpecPlot.Visible = true; %RG
            app.PlotDetailsLabel.Visible = true;
            app.LinearDataButton.Visible = true;
            app.LogDataButton.Visible=true;
            app.ExportLogDataCSVButton.Visible = true;
            app.ExportSpectraDataButton.Visible = true;
            app.DescriptionLabel.Visible = true;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = true;
            app.BackgroundLvlDisplay.Visible = true;
            app.SaveVarstoWorkspaceButton.Visible = true;
            app.LogLogFigureButton.Visible = true;
            app.ExportFiguresButton.Visible = true;
            app.ResetButton.Visible = true;
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.ButtonGroup.SelectedObject = app.LinearYscaleButton;
            app.ExportedLabel.Visible = false;
            app.UITable.Visible = true;

            % Check if TAS or TAM and sort columns by wavelength or pos
            if app.TamOn == 0
                app.waves = app.LinearData.Properties.VariableNames(2:end);
                header = app.waves;
            else
                app.waves = {Wavelength};
                app.positions = app.LinearData.Properties.VariableNames(2:end);
                header = app.positions;
            end

            % Look at the data tables and sort by respective header
            header_order = cellfun(@(x)sscanf(x, 'x%d'), header);
            header_order
            [~, sortedHeaderOrder] = sort(header_order);
            sortedHeaderOrder
            app.BackgroundLevel = app.BackgroundLevel(:,sortedHeaderOrder);

            %Change sorting array to fit Time in first index
            sortedHeaderOrder = sortedHeaderOrder + 1;
            sortedHeaderOrder = [1 sortedHeaderOrder];
            app.LogDataSort = app.LogarithmData(:, sortedHeaderOrder);
            app.LinDataSort = app.LinearData(:, sortedHeaderOrder);

            % Erase the useless 'x' char in wavelength num
            for i = 1:1:length(header)
                header(i) = erase(header(i), 'x');
            end

            % Reapply changed header to respective variables (did this to save code)
            if app.TamOn == 0
                app.waves = header;
            else
                app.positions = header;
            end

            % Sort the plotted wavelengths and find max
            app.Plot_Wavelengths = sort(str2double(app.waves));
            app.maxWave = max(app.Plot_Wavelengths);

            % Populate most important data from all files into own properties
            app.dataRG = app.LogDataSort;
            app.timeRG = app.dataRG(:,1);
            TimeRGSpec = app.LinDataSort(:,1);

            % Turn useful data into arrays
            app.TimeLinSpecArray = table2array(TimeRGSpec);
            app.TimeLogKinArray = table2array(app.timeRG);
            app.SortedYLinArray = table2array(app.LinDataSort(:,2:end));
            app.SortedYLinArrayUnfilt = app.SortedYLinArray;
%             app.SortedYLogArray = table2array(app.dataRG(:,2:end)); % Do we always take LogY for kin data?
            app.bkgLevel = table2array(app.BackgroundLevel(1,:));

            % Find the number of traces by subtracting 1 column for time
            app.Traces = size(app.LinDataSort, 2) - 1;

            % Output folder path in text box and continue
            [~, name] = fileparts(app.Path);
            app.LoadCreateError.Text = "Opened: " + name;

            RGBuildSpecData(app);

            app.LinearUnfiltered = app.LinearData;
            app.LinDataSortUnfilt = app.LinDataSort;
            app.SpecDataPlotUnfilt = app.SpecDataPlot;
            app.LogarithmUnfiltered = app.LogarithmData;
            app.LogDataSortUnfilt = app.LogDataSort;
        end

        % Button pushed function: LoadLogData
        function loadLogDataButtonPushed(app, event)
            app.LoadLogData.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [FileName, FilePath] = uigetfile('*.csv');
            app.LoadCreateError.Text = "log data: " + FileName;

            try
                % Read the data and copy to public property to use
                temp = readtable(fullfile(FilePath, FileName));
            catch ERROR
                disp(ERROR);
                app.LoadCreateError.Text = 'Function is not on selected path';
                app.LoadLogData.Enable = 'on';
                return;
            end
            app.LoadLogData.Enable = 'on';

            % Clear all previous properties and reset to default values
            clearProperties(app);
            app.Path = "";
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.ButtonGroup.SelectedObject = app.LinearYscaleButton;

            % Check if it is TAS or TAM data and edit names for global use
            if endsWith(FileName, '.csv') == 1
                if endsWith(FileName, 'TAS.csv') == 1
                    app.TamOn = 0;
                elseif endsWith(FileName, 'TAM.csv') == 1
                    app.TamOn = 1;
                else
                    msg = "Wrong input file. Need TAS or TAM data";
                    app.LoadCreateError.Text = msg;
                end
            end

            % Re-apply temp variables to properties to keep useful data
            app.dataRG = temp;


            if app.TamOn == 0
                % Extract csv header names
                app.waves = app.dataRG.Properties.VariableNames(2:end);

                % Erase useless 'x' char in wavelength num
                for i = 1:1:length(app.waves)
                    app.waves(i) = erase(app.waves(i), 'x');
                end
            else
                % Extract csv header names
                app.positions = app.dataRG.Properties.VariableNames(2:end);
                app.waves = extractBefore(app.positions,'nm');

                % Erase useless 'x' char in position num
                for i = 1:1:length(app.positions)
                    app.positions(i) = erase(app.positions(i), 'x');
                end
            end

            % Change look of application to get rid of options that are not
            % available to loaded log data
            app.IsLogDataOnly = true;
            app.Plot_Wavelengths = sort(str2double(app.waves));
            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.LogLogFigureButton.Visible = true;
            app.SpecPlot.Visible = false;
            app.PlotDetailsLabel.Visible = false;
            app.LinearDataButton.Visible = false;
            app.LogDataButton.Visible=false;
            app.ExportLogDataCSVButton.Visible = false;
            app.ExportSpectraDataButton.Visible = false;
            app.DescriptionLabel.Visible = false;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = false;
            app.BackgroundLvlDisplay.Visible = false;
            app.SaveVarstoWorkspaceButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.ResetButton.Visible = false;
            app.ExportedLabel.Visible = false;
            app.AddButton.Visible = false;
            app.UITable.Visible = false;

            % After everything is loaded, plot data accordingly
            app.timeRG = app.dataRG(:,1);
            app.KinData = app.dataRG(:,2:end);

            app.TimeLogKinArray = table2array(app.timeRG);

            plotLogData(app);
        end

        % Value changed function: LinearDataButton
        function setLinData(app, event)
            % Populate the correct property and build specdata again
            app.LogDataButton.Value = 0;
            app.dataRG = app.LinDataSort;
            app.TimeLogKinArray = table2array(app.dataRG(:,1));
            RGBuildSpecData(app);
        end

        % Value changed function: LogDataButton
        function setLogData(app, event)
            % Populate the correct property and build specdata again
            app.LinearDataButton.Value = 0;
            app.dataRG = app.LogDataSort;
            app.TimeLogKinArray = table2array(app.dataRG(:,1));
            RGBuildSpecData(app);
        end

        % Button pushed function: LogLogFigureButton
        function LogLogFigureButtonPushed(app, event)
            % Disable button while second app is open
            app.LogLogFigureButton.Enable = 'off';

            % Open LogLog App with required arguments
            app.LogApp = LogLogApp(app);
        end

        % Button pushed function: ExportFiguresButton
        function ExportFiguresButtonPushed(app, event)
            % Create string for figure titles
            string = "@" + app.Plot_Wavelengths(app.KinInd);
            name1 = sprintf('KinFigure%s.emf',string);
            name2 = sprintf('SpecFigure%s.emf',string);

            % Create full path for each name
            place1 = fullfile(app.Path,name1);
            place2 = fullfile(app.Path,name2);

            % Export Vector Image for KinPlot and PNG for SpecPlot
            exportgraphics(app.KinPlot, place1, 'ContentType','vector');
            exportgraphics(app.SpecPlot, place2, 'ContentType','vector');
        end

        % Button pushed function: SaveVarstoWorkspaceButton
        function SaveVarstoWorkspaceButtonPushed(app, event)
            % Call on these properties in order to save to workspace
            app.SpecDataPlot;
            app.dataRG;
            app.BackgroundLevel;

            % Save vars of this function to base workspace
            save_to_base(1);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Create property to know when reset button is pushed
            app.rst = 1;

            % Save path of loaded data so you don't have to call data again
            % Then clear properties and reset to default
            tempPath = app.Path;
            clearProperties(app);
            app.Path = tempPath;

            loadDataButton(app);
        end

        % Value changed function: KeepKinPlotaxisfixedCheckBox
        function KeepKinPlotaxisfixed(app, event)
            % Keep y-axis fixed for the kin plot based on log or lin
            if app.KeepKinPlotaxisfixedCheckBox.Value==1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataPlotPos = (app.KinDataArray(app.TimeLogKinArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        app.FixedYRange = [min(KinDataPlotPos) max(app.KinDataArray)];
                    case 'linear'
                        app.FixedYRange = app.KinPlot.YLim;
                end
            end

            % Check if need to re-plot Log data or Lin data
            if ~app.IsLogDataOnly
                plotSpecData(app,app.RGcolor);
                RGPlot(app,0);
            else
                plotLogData(app)
            end
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            YCheck(app);
        end

        % Selection changed function: ButtonGroup
        function selectLogOrLin(app, event)
            % Create var for selected scale and change app accordingly
            selectedButton = app.ButtonGroup.SelectedObject;

            if ~app.IsLogDataOnly
                if selectedButton == app.LogYScaleButton
                    % Delete all linear ROI objects and redraw with new log scale
                    rec = findobj(app.KinPlot,'Type','images.roi.Rectangle');
                    delete(rec);

                    app.KinPlot.YScale = 'log';
                    YCheck(app);
                    drawAreas(app);
                else
                    app.KinPlot.YScale = 'linear';
                    YCheck(app);
                    updateAreas(app);

                    if ishandle(app.KinRef)
                        delete(app.KinRef)
                        app.KinRef = yline(app.KinPlot, 0);
                    end

                    app.KinPlot.Children = [app.DataPlot; app.XPointsROI; app.KinRef];
                end
            else
                if selectedButton == app.LogYScaleButton
                    app.KinPlot.YScale = 'log';
                    YCheck(app);
                else
                    app.KinPlot.YScale = 'linear';
                    YCheck(app);
                end
            end

            % Check if the y-axis is fixed and update fixed y range
            if app.KeepKinPlotaxisfixedCheckBox.Value == 1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataPlotPos = (app.KinDataArray(app.TimeLogKinArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        app.FixedYRange = [min(KinDataPlotPos) max(app.KinDataArray)];
                    case 'linear'
                        app.FixedYRange = app.KinPlot.YLim;
                end
            end
        end

        % Value changed function: KeepSpecPlotYaxisfixedCheckBox
        function KeepSpecPlotYaxisfixed(app, event)
            % Keep y-axis fixed for the spec plot
            app.changeAxes = ~app.changeAxes;
            RGPlot(app,0)
        end

        % Button pushed function: PrevButton
        function PrevButtonPressed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd - 1;
            if app.KinInd < 1
                app.KinInd = 1;
                return
            end

            % Check if need to plot Log data or Lin data
            if ~app.IsLogDataOnly
                updateData(app);
                plotKinData(app);
                YCheck(app);
                updateAreas(app);
                plotSpecData(app,app.RGcolor);
                RGPlot(app,0);
            else
                updateData(app);
                plotLogData(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPressed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd + 1;
            if app.KinInd > app.Traces
                app.KinInd = app.Traces;
                return
            end

            % Check if need to plot loaded Log data or Lin data
            if ~app.IsLogDataOnly
                updateData(app);
                plotKinData(app);
                YCheck(app);
                updateAreas(app);
                plotSpecData(app,app.RGcolor);
                RGPlot(app,0);
            else
                updateData(app);
                plotLogData(app);
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addRow(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Store important properties about the change like indices
            newData = event.NewData;
            indices = event.Indices;
            i = indices(1);

            % Only editing time fields column and not width
            if indices(2) == 1
                % Store old and new times field values
                previousTime = app.TimeFields(i);
                newTime = str2double(newData);

                % If the number doesn't change, do nothing
                if previousTime == newTime
                    return
                elseif newData == ""
                    deleteRow(app, indices);
                    return
                elseif isnan(newTime)
                    app.UITable.Data(indices) = {sprintf('%.2e\n',previousTime)};
                    return
                end

                % Update the array spot with new data in double form
                app.TimeFields(i) = newTime;
                StartTime = newTime*(1 - (app.UITable.Data{i,2})/200);
                EndTime = newTime*(1 + (app.UITable.Data{i,2})/200);

                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];

                % Make sure the newly added/edited timeFields is sorted
                [app.TimeFields,I] = sort(app.TimeFields);
                app.TimeWidths = app.TimeWidths(I);

                % More efficient, it doesn't redraw everything, only deletes rectangles now
                Builder(app,1);
                rec = findobj(app.KinPlot,'Type','images.roi.Rectangle');
                delete(rec);
                YCheck(app);
                drawAreas(app);
                RGPlot(app,i);

                % Editing time widths
            else
                % Store old width and new width in vars
                previousWidth = app.TimeWidths(i);
                newWidth = newData;

                % If the number doesn't change, do nothing
                if previousWidth == str2double(newData)
                    return
                elseif isnan(newData)
                    deleteRow(app, indices);
                    return
                end

                % Calculate the positions of the new width from the same centre
                newWidth = newWidth/100;
                StartTime = app.TimeFields(i)*(1 - newWidth/2);
                EndTime = app.TimeFields(i) * (1 + newWidth/2);

                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];
                app.XPointsROI(i,1).Position(1) = StartTime;
                app.XPointsROI(i,1).Position(3) = app.TimeWidths(i);

                Builder(app);
                %                 updateData(app);
                %                 plotKinData(app);
                %                 YCheck(app);
                RGPlot(app,i);
            end

        end

        % Button pushed function: ExportLogDataCSVButton
        function ExportLogDataCSVButtonPushed(app, event)
            % Reveal export message
            app.ExportedLabel.Visible = true;

            % Create file name/path to export to
            [~, name1] = fileparts(app.Path);
            if app.TamOn == 0
                name = app.Path + "\" + name1 + "_KIN_TAS.csv";
                nameunf = app.Path + "\" + name1 + "_KIN_TAS_Unfiltered.csv";
            else
                name = app.Path + "\" + name1 + "_KIN_TAM.csv";
                nameunf = app.Path + "\" + name1 + "_KIN_TAM_Unfiltered.csv";
            end

            % Write CSV to table and return a message notifying as such
            writetable(app.LogDataSort, name, "Delimiter", ',', "QuoteStrings",true);
            if app.DigitalFilteringCheckBox.Value==1
                writetable(app.LogDataSort, nameunf)
            end
            app.ExportedLabel.Text = 'Data has been exported';

            % Export unfiltered if applicable
        end

        % Button pushed function: ExportSpectraDataButton
        function ExportSpectraDataButtonPushed(app, event)
            % Use path from data folder to export spectra data
            [~, name] = fileparts(app.Path);
            OutputName = app.Path + "\" + name + "_SPECTRADATA.csv";

            sorted_wavelengths = reshape(app.Plot_Wavelengths, [],1);
            wavelengths = num2str(sorted_wavelengths);
            wavelengths = strcat(wavelengths + " nm");
            DataToWrite = [wavelengths app.SpecDataPlot];

            % Create vars to export data to csv
            Headers = [];
            for i =1:1:size(app.SpecDataPlot,2)
                Headers(1,i+1) = mean(app.ShadeArea(i,:)); %#ok<AGROW>
            end

            % Concatenate data and write matrix to CSV
            OutputData = [Headers; DataToWrite];
            if app.DigitalFilteringCheckBox ==1
                OutputData(1,1) = "Time Slice";
            end

            writematrix(OutputData,OutputName,"QuoteStrings",true);

            if app.DigitalFilteringCheckBox.Value ==1
                UnfiltName = app.Path + "\" + name + "_SPECTRADATA_Unfiltered.csv";
                DataToWrite = [wavelengths app.SpecDataPlotUnfilt];
                % Create vars to export data to csv
                Headers = [];
                for i =1:1:size(app.SpecDataPlotUnfilt,2)
                    Headers(1,i+1) = mean(app.ShadeArea(i,:)); %#ok<AGROW>
                end
                OutputData = [Headers; DataToWrite];
                if app.DigitalFilteringCheckBox ==1
                    OutputData(1,1) = "Time Slice";
                end 
                writematrix(OutputData, UnfiltName);
            end

            % Reveal exported location for brief period of 10s
            app.ExportedLabel.Visible = true;
            app.ExportedLabel.Text = "Exported: " + OutputName;
            pause(10)
            app.ExportedLabel.Visible = false;

            % Export Unfiltered if applicable
        end

        % Close request function: SpectrabuilderUIFigure
        function SpectrabuilderUIFigureCloseRequest(app, event)
            % Delete both apps when closing the figures
            delete(app.LogApp)
            delete(app)
        end

        % Value changed function: DigitalFilteringCheckBox, 
        % ...and 1 other component
        function FilterChange(app, event)
            bool = app.DigitalFilteringCheckBox.Value;
            t = app.TimeFreqEditField.Value;
            if bool == 1
                app.TimeFreqEditField.Editable = 1;
                app.TimeFreqEditField.Enable = 1;
            end

            if bool == 1 % Yes Digital Filtering
                try
                    % Passband frequency is frequency window where data is
                    % alloweed to passed, stopband is where it is limited
                    fs = 1/(app.time(2)-app.time(1)); % X sampled at a rate fs in hertz
%                     fs = 1/(app.LinearUnfiltered.Time(end)-app.LinearUnfiltered.Time(1)); % X sampled at a rate fs in hertz
                    fpass = 1/t; % Passband frequency of the filter in hertz; scalar in (0, fs/2)
                    d = uiprogressdlg(app.SpectrabuilderUIFigure,'Title','Filtering','Indeterminate','on');
                    A = table2array(app.LinearUnfiltered);
%                     [Lin, filt] = bandpass(A(:,2:end), [10000,fpass], fs);
%                     [Lin, filt] = lowpass(A,fpass,fs); % Apply filtering to unfiltered data
                    
                    [b,a] = butter(2, fpass/fs, 'low'); % Use Butterworth lowpass
                    % Forward pass filter
                    filt1 = filter(b,a, A(:,2:end));
                    % Backwards pass filter
                    Lin = filter(b,a, flipud(filt1));
                    % Flip back and replace column vector
                    Lin = [A(:,1), flipud(Lin)];
                    
                    disp('Data Filtered with the following filter settings:');
                    disp(filt);
                    [LogTimeArray,LogAbsArray] = lin2log_TAS(Lin(:,1),Lin(:,2:end));
                    Lin = array2table(Lin);
                    Lin.Properties.VariableNames = app.LinearUnfiltered.Properties.VariableNames;
    
                    %Log Data Table to Array
                    LogArray = [LogTimeArray,LogAbsArray];
                    Log = array2table(LogArray);
                    Log.Properties.VariableNames = app.LogarithmUnfiltered.Properties.VariableNames;
                    app.LinearData = Lin;
                    app.LogarithmData = Log;
                    close(d)
                catch
                    close(d)
                    if app.TimeFreqEditField.Value ~= 0
                        uialert(app.SpectrabuilderUIFigure, 'There was an error filtering at this value', 'Error')
                        app.TimeFreqEditField.Value = event.PreviousValue;
                    end
                end
                
            else
                app.TimeFreqEditField.Editable = 0;
                app.TimeFreqEditField.Enable = 0;
                app.LinearData = app.LinearUnfiltered;
                app.LogarithmData = app.LogarithmUnfiltered;
            end

            % Check if TAS or TAM and sort columns by wavelength or pos
            if app.TamOn == 0
                app.waves = app.LinearData.Properties.VariableNames(2:end);
                header = app.waves;
            else
                app.waves = {Wavelength};
                app.positions = app.LinearData.Properties.VariableNames(2:end);
                header = app.positions;
            end

            % Look at the data tables and sort by respective header
            header_order = cellfun(@(x)sscanf(x, 'x%d'), header);
            [~, sortedHeaderOrder] = sort(header_order);
            app.BackgroundLevel = app.BackgroundLevel(:,sortedHeaderOrder);

            %Change sorting array to fit Time in first index
            sortedHeaderOrder = sortedHeaderOrder + 1;
            sortedHeaderOrder = [1 sortedHeaderOrder];
            app.LogDataSort = app.LogarithmData(:, sortedHeaderOrder);
            app.LogDataSortUnfilt = app.LogarithmUnfiltered(:, sortedHeaderOrder);
            app.LinDataSort = app.LinearData(:, sortedHeaderOrder);
            app.LinDataSortUnfilt = app.LinearUnfiltered(:, sortedHeaderOrder);

            % Erase the useless 'x' char in wavelength num
            for i = 1:1:length(header)
                header(i) = erase(header(i), 'x');
            end

            % Reapply changed header to respective variables (did this to save code)
            if app.TamOn == 0
                app.waves = header;
            else
                app.positions = header;
            end

            % Sort the plotted wavelengths and find max
            app.Plot_Wavelengths = sort(str2double(app.waves));
            app.maxWave = max(app.Plot_Wavelengths);

            % Populate most important data from all files into own properties
            app.dataRG = app.LogDataSort;
            app.timeRG = app.dataRG(:,1);
            TimeRGSpec = app.LinDataSort(:,1);

            % Turn useful data into arrays
            app.TimeLinSpecArray = table2array(TimeRGSpec);
            app.TimeLogKinArray = table2array(app.timeRG);
            app.SortedYLinArray = table2array(app.LinDataSort(:,2:end));
            app.SortedYLinArrayUnfilt = table2array(app.LinDataSort(:,2:end));
            
            % Find the number of traces by subtracting 1 column for time
            app.Traces = size(app.LinDataSort, 2) - 1;

            if app.LinearDataButton.Value == 1
                app.dataRG = app.LinDataSort;
                app.TimeLogKinArray = table2array(app.dataRG(:,1));
            end

            % Recalculate Stuff...
            RGBuildSpecData(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrabuilderUIFigure = uifigure('Visible', 'off');
            app.SpectrabuilderUIFigure.Name = 'Spectrabuilder';
            app.SpectrabuilderUIFigure.Icon = 'logo.png';
            app.SpectrabuilderUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectrabuilderUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SpectrabuilderUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadTDMSDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadTDMSDataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton, true);
            app.LoadTDMSDataButton.Text = 'Load TDMS Data';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.LinearDataButton = uibutton(app.GridLayout9, 'state');
            app.LinearDataButton.ValueChangedFcn = createCallbackFcn(app, @setLinData, true);
            app.LinearDataButton.Text = 'Linear Data';
            app.LogDataButton = uibutton(app.GridLayout9, 'state');
            app.LogDataButton.ValueChangedFcn = createCallbackFcn(app, @setLogData, true);
            app.LogDataButton.Text = 'Log Data';
            app.LogDataButton.Value = true;
            app.LoadLogData = uibutton(app.GridLayout2, 'push');
            app.LoadLogData.ButtonPushedFcn = createCallbackFcn(app, @loadLogDataButtonPushed, true);
            app.LoadLogData.Text = 'Load Log Data';
            app.LogLogFigureButton = uibutton(app.GridLayout2, 'push');
            app.LogLogFigureButton.ButtonPushedFcn = createCallbackFcn(app, @LogLogFigureButtonPushed, true);
            app.LogLogFigureButton.Text = 'LogLog Figure';
            app.ExportedLabel = uilabel(app.GridLayout2);
            app.ExportedLabel.Visible = 'off';
            app.ExportedLabel.Text = 'Logarithmic data will be exported to CSV';
            app.ExportFiguresButton = uibutton(app.GridLayout2, 'push');
            app.ExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFiguresButtonPushed, true);
            app.ExportFiguresButton.Text = 'Export Figures';
            app.SaveVarstoWorkspaceButton = uibutton(app.GridLayout2, 'push');
            app.SaveVarstoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVarstoWorkspaceButtonPushed, true);
            app.SaveVarstoWorkspaceButton.Text = 'Save Vars to Workspace';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.KinPlot = uiaxes(app.GridLayout3);
            title(app.KinPlot, 'Kinetics')
            xlabel(app.KinPlot, 'Time (s)')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            app.SpecPlot = uiaxes(app.GridLayout3);
            title(app.SpecPlot, 'Spectra')
            xlabel(app.SpecPlot, 'Wavelength (nm)')
            ylabel(app.SpecPlot, 'ΔOD or %Abs')
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.KeepKinPlotaxisfixedCheckBox = uicheckbox(app.GridLayout5);
            app.KeepKinPlotaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepKinPlotaxisfixed, true);
            app.KeepKinPlotaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.CenterButton = uibutton(app.GridLayout5, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.ButtonGroup = uibuttongroup(app.GridLayout5);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectLogOrLin, true);
            app.LogYScaleButton = uiradiobutton(app.ButtonGroup);
            app.LogYScaleButton.Text = 'Log Y Scale';
            app.LinearYscaleButton = uiradiobutton(app.ButtonGroup);
            app.LinearYscaleButton.Text = 'Linear Y scale';
            app.LinearYscaleButton.Value = true;
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.PrevButton = uibutton(app.GridLayout6, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPressed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.GridLayout6, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPressed, true);
            app.NextButton.Text = 'Next';
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.KeepSpecPlotYaxisfixedCheckBox = uicheckbox(app.GridLayout10);
            app.KeepSpecPlotYaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepSpecPlotYaxisfixed, true);
            app.KeepSpecPlotYaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.DigitalFilteringCheckBox = uicheckbox(app.GridLayout10);
            app.DigitalFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterChange, true);
            app.DigitalFilteringCheckBox.Text = 'Digital Filtering:';
            app.TimeFreqEditField = uieditfield(app.GridLayout10, 'numeric');
            app.TimeFreqEditField.ValueChangedFcn = createCallbackFcn(app, @FilterChange, true);
            app.TimeFreqEditField.Editable = 'off';
            app.TimeFreqEditField.Enable = 'off';
            app.TimeFreqLabel = uilabel(app.GridLayout10);
            app.TimeFreqLabel.Text = 'secs';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.PlotDetailsLabel = uilabel(app.GridLayout4);
            app.PlotDetailsLabel.Text = 'Plot Details';
            app.DescriptionLabel = uilabel(app.GridLayout4);
            app.DescriptionLabel.Text = 'Center times & widths for spectra';
            app.FileMetadataLabel = uilabel(app.GridLayout4);
            app.FileMetadataLabel.Text = 'File Metadata';
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Centre Time'; 'Time Width (%)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BackgroundLvlDisplay = uilabel(app.GridLayout4);
            app.BackgroundLvlDisplay.Text = 'Background level is _ V';
            app.KineticLevelDisplay = uilabel(app.GridLayout4);
            app.KineticLevelDisplay.Text = 'Displaying kinetic at _ nm';
            app.Channel = uilabel(app.GridLayout4);
            app.Channel.Text = '';
            app.ExportLogDataCSVButton = uibutton(app.GridLayout4, 'push');
            app.ExportLogDataCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogDataCSVButtonPushed, true);
            app.ExportLogDataCSVButton.Text = 'Export Log Data CSV';
            app.ExportSpectraDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportSpectraDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraDataButtonPushed, true);
            app.ExportSpectraDataButton.Text = 'Export SpectraData';
            % Show the figure after all components are created
            app.SpectrabuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarSpecGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrabuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrabuilderUIFigure)
        end
    end
end